classdef ForceCal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        MeasurementSetupTab           matlab.ui.container.Tab
        BalanceInfoDistanceisdistancefromelementtobalancecenterPanel  matlab.ui.container.Panel
        Desc                          matlab.ui.control.Table
        MaxLoads                      matlab.ui.control.Table
        BalanceTypeDropDown           matlab.ui.control.DropDown
        BalanceTypeDropDownLabel      matlab.ui.control.Label
        AvailableDAQsDropDown         matlab.ui.control.DropDown
        AvailableDAQsDropDownLabel    matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        CalibrationProcedureTab       matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        MomentArminEditField          matlab.ui.control.NumericEditField
        MomentArminEditFieldLabel     matlab.ui.control.Label
        GenerateReportButton          matlab.ui.control.Button
        WritetoFileButton             matlab.ui.control.Button
        SecondsEditField              matlab.ui.control.NumericEditField
        SecondsEditFieldLabel         matlab.ui.control.Label
        AcquireTestPointButton        matlab.ui.control.Button
        LoadOrientationDropDown       matlab.ui.control.DropDown
        LoadOrientationDropDownLabel  matlab.ui.control.Label
        MTable                        matlab.ui.control.Table
        TimeHistoryEvalTab            matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Daq         % Description
    end
    
    properties (Access = public)
        Data % Description
    end

    methods (Access = private)

        function str = range_to_str(app,Range)
            str = [num2str(Range.Min) ',' num2str(Range.Max)];
        end

        function  update_table_from_channels(app)
            app.UITable.Data = {};
            for i = 1:length(app.Daq.dev.Channels)
                app.UITable.Data = [app.UITable.Data;{i,app.Daq.dev.Channels(i).Type,app.Daq.dev.Channels(i).ID,...
                    app.Daq.dev.Channels(i).TerminalConfig, range_to_str(app,app.Daq.dev.Channels(i).Range),...
                    app.Daq.dev.Channels(i).Name}];
                name{i} = app.Daq.dev.Channels(i).Name;


            end
            c=1;
            for i = 1:length(name)-1
                app.LoadOrientationDropDown.Items{c} = [name{i} '_pos'];
                c=c+1;
                app.LoadOrientationDropDown.Items{c} = [name{i} '_neg'];
                c=c+1;
            end
            app.MTable.ColumnName = [app.LoadOrientationDropDown.Value, name];
            app.MTable.ColumnFormat = {'short','shorte','shorte','shorte','shorte','shorte', 'shorte','short'};
            app.AcquireTestPointButton.Text = ['Acquire Test Point - [' app.LoadOrientationDropDown.Value ']'];
            app.Data.names =name;
        end
        function update_measurement_table(app,load,data)            
            app.MTable.Data = [app.MTable.Data;[load data]];
            if isfield(app.Data,app.LoadOrientationDropDown.Value)
            app.Data.(app.LoadOrientationDropDown.Value) = ...
                [app.Data.(app.LoadOrientationDropDown.Value);[load data]];
            else
                app.Data.(app.LoadOrientationDropDown.Value) = [load data];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DefineDefaults(app)
            app.AvailableDAQsDropDown.Items = {'Search for Devices'};
        end

        % Value changed function: AvailableDAQsDropDown
        function ChangeDev(app, event)
            value = app.AvailableDAQsDropDown.Value;

            for i = 1:length(app.AvailableDAQsDropDown.Items)
                if contains(value,app.AvailableDAQsDropDown.Items{i})
                    app.Daq.ind = i-1;
                end
            end
            app.Daq.dev = [];
            app.Daq.dev = daq(app.Daq.devs(app.Daq.ind,1).VendorID);
            dev_info = app.Daq.devs(app.Daq.ind,5).DeviceInfo.Subsystems(1,1);
            app.UITable.ColumnFormat(3) = {dev_info.ChannelNames'};
            app.UITable.ColumnEditable(3) = true;
            %app.UITable.ColumnFormat(5) = [];
            str_cell = {};
            for i = 1:length(dev_info.RangesAvailable)
                str = range_to_str(app,dev_info.RangesAvailable(i));
                str_cell = [str_cell; {str}];
            end
            app.UITable.ColumnFormat(5) = {str_cell'};
            app.UITable.ColumnEditable(5) = true;
            app.UITable.ColumnEditable(6) = true;
            BalanceTypeChanged(app)
        end

        % Drop down opening function: AvailableDAQsDropDown
        function GetDevs(app, event)

            devs = daqlist;
            app.Daq.devs = devs;
            for i =1:size(devs,1)
                app.AvailableDAQsDropDown.Items = ['-------------' ; strcat(devs.VendorID,'-',devs.DeviceID,'-',devs.Model)];
            end
        end

        % Value changed function: BalanceTypeDropDown
        function BalanceTypeChanged(app, event)
            value = app.BalanceTypeDropDown.Value;
            if ~isempty(app.Daq.dev.Channels)
                removechannel(app.Daq.dev,1:length(app.Daq.dev.Channels))
                app.UITable.Data = {};
            end
            if contains(value,'Force Balance')
                % Force Balance Table
                names = {'N1' 'N2' 'Y1' 'Y2'  'Axial' 'Roll' 'Excitation'};
                type = '5-Force / 1-Moment';

            else
                names = {'Aft_Pitch' 'Aft_Yaw' 'Fwd_Pitch' 'Fwd_Yaw'  'Axial' 'Roll' 'Excitation'};
                type = '1-Force / 5-Moment';
            end
            app.MaxLoads.Data = strings(length(names)-1,3);
            for i =1:length(names)-1
                app.MaxLoads.Data(i,1) = names{i};
            end

            app.Desc.Data =strings;
            app.Desc.Data(1,1) = "Cal'd by:";

            app.Desc.Data(2,1) = "Bal Type: " ;
            app.Desc.Data(2,2) =  type ;
            
            app.Desc.Data(3,1) = "Bal Serial #: " ;
            app.Desc.Data(4,1) = "Bal Diameter: " ;
            app.Desc.Data(5,1) = "Date:" ;
            app.Desc.Data(5,2) = datetime("today");

            for i =1:7
                ch = ['ai' num2str(i-1)];
                addinput(app.Daq.dev,app.Daq.devs(app.Daq.ind,2).DeviceID,ch,'Voltage');
                app.Daq.dev.Channels(i).Name = names{i};
                app.Daq.dev.Channels(i).Range = [-.2 .2];
                %app.Daq.dev.Channels(i).Rate  = 1000;
                %                 app.UITable.Data = [app.UITable.Data;{i,app.Daq.dev.Channels(i).Type,app.Daq.dev.Channels(i).ID,...
                %                     app.Daq.dev.Channels(i).TerminalConfig, range_to_str(app,app.Daq.dev.Channels(i).Range),...
                %                     app.Daq.dev.Channels(i).Name}];
            end
            update_table_from_channels(app)
            %app.LoadOrientationDropDown(app)
            LoadOrientationDropDownValueChanged(app)

        end

        % Cell edit callback: UITable
        function UITableSelectionChanged(app, event)
            ind = app.UITable.Selection;
            n = length(app.Daq.dev.Channels);
            if ind(2)==3  % Channel Change
                %app.Daq.dev.Channels(ind(1)).ID = event.NewData;
                removechannel(app.Daq.dev,ind(1))
                addinput(app.Daq.dev,app.Daq.devs(app.Daq.ind,2).DeviceID,event.NewData,'Voltage');
                name = app.UITable.Data(ind(1),6);
                app.Daq.dev.Channels(n).Name = name{1};
                app.Daq.dev.Channels(n).Range = [-.2 .2];
            end
            if ind(2)==5  % Channel Change
                ID = app.Daq.dev.Channels(ind(1)).ID;
                removechannel(app.Daq.dev,ind(1))
                addinput(app.Daq.dev,app.Daq.devs(app.Daq.ind,2).DeviceID,ID,'Voltage');
                name = app.UITable.Data(ind(1),6);
                app.Daq.dev.Channels(n).Name = name{1};
                range = strsplit(event.NewData,',');
                app.Daq.dev.Channels(n).Range = [str2double(range{1}) str2double(range{2})];
            end
            update_table_from_channels(app)
        end

        % Button pushed function: AcquireTestPointButton
        function AcquireTestPointButtonPushed(app, event)

            load = inputdlg(['Input Load - ' app.LoadOrientationDropDown.Value]);
            load = str2double(load{1})*app.MomentArminEditField.Value;
            if ~isempty(load)
                t = app.SecondsEditField.Value;
                data = read(app.Daq.dev,seconds(t),"OutputFormat","Matrix");
                time = .001:.001:t;
                leg = {};
                hold(app.UIAxes,'off')
                for i = 1:size(data,2)
                    plot(app.UIAxes,time, data(:,i))
                    hold(app.UIAxes,'on')
                    leg = [strrep(leg,'_',' '),app.UITable.Data{i,6}];
                end
                %             app.UIAxes.XLabel('Time [s]')
                %             app.UIAxes.YLabel('Voltage')
                legend(app.UIAxes,leg)
                axis(app.UIAxes,'tight')
                grid(app.UIAxes,'on')
                ylim(app.UIAxes,[-.01 .01])
                means = mean(data,1);                
                update_measurement_table(app,load,means)
            end
        end

        % Value changed function: LoadOrientationDropDown
        function LoadOrientationDropDownValueChanged(app, event)
            %value = app.LoadOrientationDropDown.Value;
            app.MTable.ColumnName{1} = app.LoadOrientationDropDown.Value;
            app.AcquireTestPointButton.Text = ['Acquire Test Point ' [app.LoadOrientationDropDown.Value]];
            for i = 1:length(app.LoadOrientationDropDown.Items)
                if contains(app.LoadOrientationDropDown.Items{i},app.LoadOrientationDropDown.Value)
                    app.Data.Load_index = i;
                end
            end
            if isfield(app.Data,app.LoadOrientationDropDown.Value)
                app.MTable.Data = app.Data.(app.LoadOrientationDropDown.Value);
            else
                app.MTable.Data = [];
            end
            % Add switch to update moment arm
            switch app.BalanceTypeDropDown.Value
                case 'Force Balance'
                    type = 'FB_';
                    app.MomentArminEditField.Value = 1;
                case 'Moment Balance'
                    type = 'MB_';
                    if contains(app.LoadOrientationDropDown.Value,'Pitch')
                        dist = str2double(app.MaxLoads.Data(1,3));
                    elseif contains(app.LoadOrientationDropDown.Value,'Yaw')
                        dist = str2double(app.MaxLoads.Data(2,3));
                    else
                        dist = nan;
                    end
                    if isnan(dist)
                        warning('No distance entered')
                    else 
                        app.MomentArminEditField.Value = 2*dist;
                    end
            end
            if contains(app.LoadOrientationDropDown.Value,'Roll')
                dist = str2double(app.MaxLoads.Data(6,3));
                if isnan(dist)
                    dist = 2;
                end
                app.MomentArminEditField.Value = dist;
            end
            image = [type app.LoadOrientationDropDown.Value '.png'];
            app.Daq.name = image;
            app.Image.ImageSource = image;
        end

        % Cell selection callback: MTable
        function MTableCellSelection(app, event)
            answer = questdlg('Would you like to delete selected row?', ...
	        'Row Deletion Question', ...
	        {'Yes','No'});
            if strcmp(answer,'Yes')
                indices = event.Indices;
                app.MTable.Data = removerows(app.MTable.Data,indices(1));
                app.Data.(app.LoadOrientationDropDown.Value) = removerows(app.Data.(app.LoadOrientationDropDown.Value),indices(1));
            end
            
        end

        % Button pushed function: WritetoFileButton
        function WritetoFileButtonPushed(app, event)
            [file,path] = uiputfile({'*.vol';'*.mat';'*.*'},'Select file to save');
            [~, ~, ext] = fileparts(file);
            switch ext
                case '.vol'
                    write_vol_from_app([path file] ,app.Data,app.MaxLoads,app.Desc);
                case '.mat'
                    disp('not implemented')
            end


        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            options = struct('format','pdf','codeToEvaluate','FB_Cal_Summary');
            publish('FB_Cal_Summary.m',options);
            close all
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            figure
            [im,~, sample_alpha] = imread(app.Daq.name);
            image(im,'AlphaData', sample_alpha);
            set(gca,'XColor', 'none','YColor','none')
            set(gca, 'color', 'none');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeasurementSetupTab = uitab(app.TabGroup);
            app.MeasurementSetupTab.Title = 'Measurement Setup';
            app.UITable = uitable(app.MeasurementSetupTab);
            app.UITable.ColumnName = {'Index'; 'Type'; 'Channel'; 'Measurement Type'; 'Range [volts]'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.AvailableDAQsDropDownLabel = uilabel(app.MeasurementSetupTab);
            app.AvailableDAQsDropDownLabel.Text = 'Available DAQs';
            app.AvailableDAQsDropDown = uidropdown(app.MeasurementSetupTab);
            app.AvailableDAQsDropDown.Items = {'Check for devs', 'Option 2', 'Option 3', 'Option 4'};
            app.AvailableDAQsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GetDevs, true);
            app.AvailableDAQsDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDev, true);
            app.AvailableDAQsDropDown.Value = 'Check for devs';
            app.BalanceTypeDropDownLabel = uilabel(app.MeasurementSetupTab);
            app.BalanceTypeDropDownLabel.Text = 'Balance Type';
            app.BalanceTypeDropDown = uidropdown(app.MeasurementSetupTab);
            app.BalanceTypeDropDown.Items = {'Force Balance', 'Moment Balance'};
            app.BalanceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @BalanceTypeChanged, true);
            app.BalanceTypeDropDown.Value = 'Force Balance';
            app.BalanceInfoDistanceisdistancefromelementtobalancecenterPanel = uipanel(app.MeasurementSetupTab);
            app.BalanceInfoDistanceisdistancefromelementtobalancecenterPanel.Title = 'Balance Info -  Distance is distance from element to balance center';
            app.MaxLoads = uitable(app.BalanceInfoDistanceisdistancefromelementtobalancecenterPanel);
            app.MaxLoads.ColumnName = {'Element'; 'Max Load'; 'Distance [in]'};
            app.MaxLoads.RowName = {};
            app.MaxLoads.ColumnEditable = [false true true];
            app.Desc = uitable(app.BalanceInfoDistanceisdistancefromelementtobalancecenterPanel);
            app.Desc.ColumnName = {'Additional Info'; ''};
            app.Desc.RowName = {};
            app.Desc.ColumnEditable = [false true];
            app.CalibrationProcedureTab = uitab(app.TabGroup);
            app.CalibrationProcedureTab.Title = 'Calibration Procedure';
            app.MTable = uitable(app.CalibrationProcedureTab);
            app.MTable.ColumnName = {'Load Configuration'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MTable.RowName = {};
            app.MTable.CellSelectionCallback = createCallbackFcn(app, @MTableCellSelection, true);
            app.LoadOrientationDropDownLabel = uilabel(app.CalibrationProcedureTab);
            app.LoadOrientationDropDownLabel.Text = 'Load Orientation';
            app.LoadOrientationDropDown = uidropdown(app.CalibrationProcedureTab);
            app.LoadOrientationDropDown.Items = {};
            app.LoadOrientationDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadOrientationDropDownValueChanged, true);
            app.LoadOrientationDropDown.Value = {};
            app.AcquireTestPointButton = uibutton(app.CalibrationProcedureTab, 'push');
            app.AcquireTestPointButton.ButtonPushedFcn = createCallbackFcn(app, @AcquireTestPointButtonPushed, true);
            app.AcquireTestPointButton.Text = 'Acquire Test Point';
            app.SecondsEditFieldLabel = uilabel(app.CalibrationProcedureTab);
            app.SecondsEditFieldLabel.Text = 'Seconds';
            app.SecondsEditField = uieditfield(app.CalibrationProcedureTab, 'numeric');
            app.SecondsEditField.Value = 1;
            app.WritetoFileButton = uibutton(app.CalibrationProcedureTab, 'push');
            app.WritetoFileButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoFileButtonPushed, true);
            app.WritetoFileButton.Text = 'Write to File';
            app.GenerateReportButton = uibutton(app.CalibrationProcedureTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.MomentArminEditFieldLabel = uilabel(app.CalibrationProcedureTab);
            app.MomentArminEditFieldLabel.Text = 'Moment Arm [in]';
            app.MomentArminEditField = uieditfield(app.CalibrationProcedureTab, 'numeric');
            app.Image = uiimage(app.CalibrationProcedureTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.TimeHistoryEvalTab = uitab(app.TabGroup);
            app.TimeHistoryEvalTab.Title = 'Time History Eval';
            app.UIAxes = uiaxes(app.TimeHistoryEvalTab);
            xlabel(app.UIAxes, 'Time [s]', 'Interpreter', 'latex')
            ylabel(app.UIAxes, 'Voltage', 'Interpreter', 'latex')
            zlabel(app.UIAxes, 'Z', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceCal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @DefineDefaults)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end